classdef AAPTS_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AutomatedArduinoParkingTrackingSystemAAPTSLabel  matlab.ui.control.Label
        TabGroup            matlab.ui.container.TabGroup
        ArduinoTestTab      matlab.ui.container.Tab
        Gauge               matlab.ui.control.LinearGauge
        StartButton         matlab.ui.control.Button
        StatusLabel         matlab.ui.control.Label
        Label               matlab.ui.control.Label
        DistanceReadLabel   matlab.ui.control.Label
        CarStatisticsLabel  matlab.ui.control.Label
        Lamp                matlab.ui.control.Lamp
        CarParkingSlotsTab  matlab.ui.container.Tab
        Slot8StatusLabel    matlab.ui.control.Label
        Slot4StatusLabel    matlab.ui.control.Label
        Slot7StatusLabel    matlab.ui.control.Label
        Slot3StatusLabel    matlab.ui.control.Label
        Slot6StatusLabel    matlab.ui.control.Label
        Slot2StatusLabel    matlab.ui.control.Label
        Slot5StatusLabel    matlab.ui.control.Label
        Slot1StatusLabel    matlab.ui.control.Label
        OccupiedLabel       matlab.ui.control.Label
        AvailableLabel      matlab.ui.control.Label
        TotalSlots8Label    matlab.ui.control.Label
        Slot8Button         matlab.ui.control.Button
        Slot7Button_2       matlab.ui.control.Button
        Slot6Button_2       matlab.ui.control.Button
        Slot5Button_2       matlab.ui.control.Button
        Slot4Button         matlab.ui.control.Button
        Slot3Button         matlab.ui.control.Button
        Slot2Button         matlab.ui.control.Button
        LampSlot8           matlab.ui.control.Lamp
        LampSlot7           matlab.ui.control.Lamp
        LampSlot6           matlab.ui.control.Lamp
        LampSlot5           matlab.ui.control.Lamp
        Slot1Button         matlab.ui.control.Button
        LampSlot4           matlab.ui.control.Lamp
        LampSlot3           matlab.ui.control.Lamp
        LampSlot2           matlab.ui.control.Lamp
        LampSlot1           matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        ultrasonicObj % Description
        a % Description
        isLooping % Description
        numOccupied
        occupiedSlots
    end
    
    methods (Access = public)
        
        function updateDistance(app, lamp, statusLabel, slotNumber)
            distance = 100.0*readDistance(app.ultrasonicObj)
            app.DistanceReadLabel.Text = "Distance: "+string(distance);
            app.Gauge.Value = distance;
            if distance <= 5
                lamp.Color = "red";
                statusLabel.Text = statusLabel.Text + ": Occupied";
                if slotNumber ~= 0
                    app.occupiedSlots(slotNumber) = 1;
                end
            else
                lamp.Color = "green";
                statusLabel.Text = statusLabel.Text + ": Available";
                if slotNumber ~= 0
                    app.occupiedSlots(slotNumber) = 0;
                end
            end
            updateNumSlot(app)
            drawnow
            pause(0.5)
        end

        function updateNumSlot(app)
            numOccupy = sum(app.occupiedSlots);
            numAvailable = 8-sum(app.occupiedSlots);
            app.OccupiedLabel.Text = "Occupied: " + string(numOccupy);
            app.AvailableLabel.Text = "Available: " + string(numAvailable);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %clear all;
            app.a = arduino('COM6', 'Uno', 'Libraries', 'Ultrasonic');

            app.ultrasonicObj = ultrasonic(app.a,'D6','D7');
            
            app.isLooping = false;
            app.numOccupied = 0;
            app.occupiedSlots = [0; 0; 0; 0; 0; 0; 0; 0];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Change state of isLooping
            app.isLooping = ~app.isLooping;

            % Change button text
            if app.isLooping
                app.StartButton.Text = "Stop";
            else
                app.StartButton.Text = "Start";
            end

            % :)
            while app.isLooping
                app.StatusLabel.Text = "Status"
                updateDistance(app, app.Lamp, app.StatusLabel, 0);
            end
        end

        % Button pushed function: Slot1Button
        function Slot1ButtonPushed(app, event)
            app.Slot1StatusLabel.Text = "#1";
            updateDistance(app, app.LampSlot1, app.Slot1StatusLabel, 1);
      
           
        end

        % Button pushed function: Slot2Button
        function Slot2ButtonPushed(app, event)
            app.Slot2StatusLabel.Text = "#2";
            updateDistance(app, app.LampSlot2, app.Slot2StatusLabel, 2);
        end

        % Button pushed function: Slot3Button
        function Slot3ButtonPushed(app, event)
            app.Slot3StatusLabel.Text = "#3";
            updateDistance(app, app.LampSlot3, app.Slot3StatusLabel, 3);
        end

        % Button pushed function: Slot4Button
        function Slot4ButtonPushed(app, event)
            app.Slot4StatusLabel.Text = "#4";
            updateDistance(app, app.LampSlot4, app.Slot4StatusLabel, 4);
        end

        % Button pushed function: Slot5Button_2
        function Slot5Button_2Pushed(app, event)
            app.Slot5StatusLabel.Text = "#5";
            updateDistance(app, app.LampSlot5, app.Slot5StatusLabel, 5);
        end

        % Button pushed function: Slot6Button_2
        function Slot6Button_2Pushed(app, event)
            app.Slot6StatusLabel.Text = "#6";
            updateDistance(app, app.LampSlot6, app.Slot6StatusLabel, 6);
        end

        % Button pushed function: Slot7Button_2
        function Slot7Button_2Pushed(app, event)
            app.Slot7StatusLabel.Text = "#7";
            updateDistance(app, app.LampSlot7, app.Slot7StatusLabel, 7);
        end

        % Button pushed function: Slot8Button
        function Slot8ButtonPushed(app, event)
            app.Slot8StatusLabel.Text = "#8";
            updateDistance(app, app.LampSlot8, app.Slot8StatusLabel, 8);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ArduinoTestTab = uitab(app.TabGroup);
            app.ArduinoTestTab.Title = 'Arduino Test';
            app.Lamp = uilamp(app.ArduinoTestTab);
            app.CarStatisticsLabel = uilabel(app.ArduinoTestTab);
            app.CarStatisticsLabel.Text = 'Car Statistics';
            app.DistanceReadLabel = uilabel(app.ArduinoTestTab);
            app.DistanceReadLabel.Text = 'Distance Read: ';
            app.Label = uilabel(app.ArduinoTestTab);
            app.Label.Text = '';
            app.StatusLabel = uilabel(app.ArduinoTestTab);
            app.StatusLabel.Text = 'Status: ';
            app.StartButton = uibutton(app.ArduinoTestTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Gauge = uigauge(app.ArduinoTestTab, 'linear');
            app.Gauge.Limits = [0 40];
            app.CarParkingSlotsTab = uitab(app.TabGroup);
            app.CarParkingSlotsTab.Title = 'Car Parking Slots';
            app.LampSlot1 = uilamp(app.CarParkingSlotsTab);
            app.LampSlot2 = uilamp(app.CarParkingSlotsTab);
            app.LampSlot3 = uilamp(app.CarParkingSlotsTab);
            app.LampSlot4 = uilamp(app.CarParkingSlotsTab);
            app.Slot1Button = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot1Button.ButtonPushedFcn = createCallbackFcn(app, @Slot1ButtonPushed, true);
            app.Slot1Button.Text = 'Slot 1';
            app.LampSlot5 = uilamp(app.CarParkingSlotsTab);
            app.LampSlot6 = uilamp(app.CarParkingSlotsTab);
            app.LampSlot7 = uilamp(app.CarParkingSlotsTab);
            app.LampSlot8 = uilamp(app.CarParkingSlotsTab);
            app.Slot2Button = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot2Button.ButtonPushedFcn = createCallbackFcn(app, @Slot2ButtonPushed, true);
            app.Slot2Button.Text = 'Slot 2';
            app.Slot3Button = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot3Button.ButtonPushedFcn = createCallbackFcn(app, @Slot3ButtonPushed, true);
            app.Slot3Button.Text = 'Slot 3';
            app.Slot4Button = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot4Button.ButtonPushedFcn = createCallbackFcn(app, @Slot4ButtonPushed, true);
            app.Slot4Button.Text = 'Slot 4';
            app.Slot5Button_2 = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot5Button_2.ButtonPushedFcn = createCallbackFcn(app, @Slot5Button_2Pushed, true);
            app.Slot5Button_2.Text = 'Slot 5';
            app.Slot6Button_2 = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot6Button_2.ButtonPushedFcn = createCallbackFcn(app, @Slot6Button_2Pushed, true);
            app.Slot6Button_2.Text = 'Slot 6';
            app.Slot7Button_2 = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot7Button_2.ButtonPushedFcn = createCallbackFcn(app, @Slot7Button_2Pushed, true);
            app.Slot7Button_2.Text = 'Slot 7';
            app.Slot8Button = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot8Button.ButtonPushedFcn = createCallbackFcn(app, @Slot8ButtonPushed, true);
            app.Slot8Button.Text = 'Slot 8';
            app.TotalSlots8Label = uilabel(app.CarParkingSlotsTab);
            app.TotalSlots8Label.Text = 'Total Slots: 8';
            app.AvailableLabel = uilabel(app.CarParkingSlotsTab);
            app.AvailableLabel.Text = 'Available: ';
            app.OccupiedLabel = uilabel(app.CarParkingSlotsTab);
            app.OccupiedLabel.Text = 'Occupied: ';
            app.Slot1StatusLabel = uilabel(app.CarParkingSlotsTab);
            app.Slot1StatusLabel.Text = '#1: Set Status';
            app.Slot5StatusLabel = uilabel(app.CarParkingSlotsTab);
            app.Slot5StatusLabel.Text = '#5: Set Status';
            app.Slot2StatusLabel = uilabel(app.CarParkingSlotsTab);
            app.Slot2StatusLabel.Text = '#2: Set Status';
            app.Slot6StatusLabel = uilabel(app.CarParkingSlotsTab);
            app.Slot6StatusLabel.Text = '#6: Set Status';
            app.Slot3StatusLabel = uilabel(app.CarParkingSlotsTab);
            app.Slot3StatusLabel.Text = '#3: Set Status';
            app.Slot7StatusLabel = uilabel(app.CarParkingSlotsTab);
            app.Slot7StatusLabel.Text = '#7: Set Status';
            app.Slot4StatusLabel = uilabel(app.CarParkingSlotsTab);
            app.Slot4StatusLabel.Text = '#4: Set Status';
            app.Slot8StatusLabel = uilabel(app.CarParkingSlotsTab);
            app.Slot8StatusLabel.Text = '#8: Set Status';
            app.AutomatedArduinoParkingTrackingSystemAAPTSLabel = uilabel(app.UIFigure);
            app.AutomatedArduinoParkingTrackingSystemAAPTSLabel.Text = 'Automated Arduino Parking Tracking System (AAPTS)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AAPTS_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end